classdef image_sub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SubtractButton                matlab.ui.control.Button
        UploadtheImagesButton         matlab.ui.control.Button
        EXPORTButton                  matlab.ui.control.Button
        CONTRASTKnob                  matlab.ui.control.Knob
        CONTRASTKnobLabel             matlab.ui.control.Label
        BRIGHTNESSKnob                matlab.ui.control.Knob
        BRIGHTNESSKnobLabel           matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        BoxFilterSpinner              matlab.ui.control.Spinner
        BoxFilterSpinnerLabel         matlab.ui.control.Label
        SelectthefilterDropDown       matlab.ui.control.DropDown
        SelectthefilterDropDownLabel  matlab.ui.control.Label
        GaussianFilterSlider          matlab.ui.control.Slider
        GaussianFilterSliderLabel     matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        UIAxes_3                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        img1;
        img2;
        img3;% Description
        imgfilter;
        fvalue;
        imgbright;
        imgcontr;
        final;
        brightness=0;
        contrast=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadtheImagesButton
        function UploadtheImagesButtonPushed(app, event)
            [fileName1, filePath1] = uigetfile({'*.jpg;*.jpeg;*.png', 'Image Files (*.jpg, *.jpeg, *.png)'}, 'Select an image file');
            fullFilePath1 = fullfile(filePath1, fileName1);
            imageData1 = imread(fullFilePath1);
            app.img1=imageData1;
            
            [fileName2, filePath2] = uigetfile({'*.jpg;*.jpeg;*.png', 'Image Files (*.jpg, *.jpeg, *.png)'}, 'Select an image file');
            if fileName2 == 0
                return;
            end
            fullFilePath2 = fullfile(filePath2, fileName2);
            imageData2 = imread(fullFilePath2);
            app.img2=imageData2;
            
     
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            desiredSize = [size(app.img1, 1), size(app.img1, 2)]; % Specify desired size
            image1_resized = imresize(app.img1, desiredSize);
            image2_resized = imresize(app.img2, desiredSize);

            % Convert the images to double precision for accurate subtraction
            image1_double = im2double(image1_resized);
            image2_double = im2double(image2_resized);
            sub = imsubtract(image1_double, image2_double);
            imshow(sub, 'Parent', app.UIAxes_3);
            app.img3=sub;
        end

        % Value changing function: BRIGHTNESSKnob
        function BRIGHTNESSKnobValueChanging(app, event)
            brightness_offset = event.Value;
            app.brightness=brightness_offset;
            if (app.contrast)==0
                originalImage=app.imgfilter;
                % Read the image
                originalImage = im2double(originalImage);
                brightenedImage = originalImage + brightness_offset;
                % Clip the values to ensure they remain in the valid range [0, 1]
                brightenedImage = max(0, min(brightenedImage, 1));
                imshow(brightenedImage, 'Parent', app.UIAxes_3);
                app.imgbright=brightenedImage;
                app.final=brightenedImage;
            else
                originalImage=app.imgcontr;
                % Read the image
                originalImage = im2double(originalImage);
                brightenedImage = originalImage + brightness_offset;
                % Clip the values to ensure they remain in the valid range [0, 1]
                brightenedImage = max(0, min(brightenedImage, 1));
                imshow(brightenedImage, 'Parent', app.UIAxes_3);
                app.imgbright=brightenedImage;
                app.final=brightenedImage;
            end
          
        end

        % Value changing function: CONTRASTKnob
        function CONTRASTKnobValueChanging(app, event)
            changingValue = event.Value;
            app.contrast=changingValue;
            if (app.brightness)==0
                originalImage=app.imgfilter;
                originalImage = im2double(originalImage);
                % Adjust contrast by multiplying with contrastFactor
                adjustedImage = originalImage * changingValue;
                % Clip the values to ensure they remain in the valid range [0, 1]
                adjustedImage = max(0, min(adjustedImage, 1));
                imshow(adjustedImage, 'Parent', app.UIAxes_3);
                app.imgcontr=adjustedImage;
                app.final=app.imgcontr;
            else
                originalImage=app.imgbright;
                originalImage = im2double(originalImage);
                % Adjust contrast by multiplying with contrastFactor
                adjustedImage = originalImage * changingValue;
                % Clip the values to ensure they remain in the valid range [0, 1]
                adjustedImage = max(0, min(adjustedImage, 1));
                imshow(adjustedImage, 'Parent', app.UIAxes_3);
                app.imgcontr=adjustedImage;
                app.final=app.imgcontr;
            end
           
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)

            outputPath = 'C:\Users\achuj\Documents\_IMG_SUB\exported_image.jpg'; % Change the path and filename as needed
            imwrite((app.final), outputPath);
        end

        % Value changing function: GaussianFilterSlider
        function GaussianFilterSliderValueChanging(app, event)
            if app.fvalue==0
                changingValue = event.Value;
                filter = imgaussfilt((app.img3), changingValue);
                imshow(filter, 'Parent', app.UIAxes_3);
                app.imgfilter=filter;
            end
        end

        % Value changed function: SelectthefilterDropDown
        function SelectthefilterDropDownValueChanged(app, event)
            value = app.SelectthefilterDropDown.Value;
            if value=="Gaussian Filter"
                app.fvalue=0;
            else
                app.fvalue=1;
            end
        end

        % Value changing function: BoxFilterSpinner
        function BoxFilterSpinnerValueChanging(app, event)
            value = event.Value;
            if app.fvalue==1
                boxfilter = imboxfilt((app.img3),value);
                imshow(boxfilter, 'Parent', app.UIAxes_3);
                app.imgfilter=boxfilter;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {472, 472};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {164, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BRIGHTNESSKnobLabel = uilabel(app.LeftPanel);
            app.BRIGHTNESSKnobLabel.Text = 'BRIGHTNESS';
            app.BRIGHTNESSKnob = uiknob(app.LeftPanel, 'continuous');
            app.BRIGHTNESSKnob.Limits = [-0.75 0.75];
            app.BRIGHTNESSKnob.ValueChangingFcn = createCallbackFcn(app, @BRIGHTNESSKnobValueChanging, true);
            app.CONTRASTKnobLabel = uilabel(app.LeftPanel);
            app.CONTRASTKnobLabel.Text = 'CONTRAST';
            app.CONTRASTKnob = uiknob(app.LeftPanel, 'continuous');
            app.CONTRASTKnob.Limits = [0.25 2];
            app.CONTRASTKnob.ValueChangingFcn = createCallbackFcn(app, @CONTRASTKnobValueChanging, true);
            app.CONTRASTKnob.Value = 1;
            app.EXPORTButton = uibutton(app.LeftPanel, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.UploadtheImagesButton = uibutton(app.LeftPanel, 'push');
            app.UploadtheImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtheImagesButtonPushed, true);
            app.UploadtheImagesButton.Text = 'Upload the Images';
            app.SubtractButton = uibutton(app.LeftPanel, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes_3 = uiaxes(app.GridLayout2);
            title(app.UIAxes_3, 'Output')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.Value = 'Image Subtractor';
            app.GaussianFilterSliderLabel = uilabel(app.GridLayout2);
            app.GaussianFilterSliderLabel.Text = 'Gaussian Filter';
            app.GaussianFilterSlider = uislider(app.GridLayout2);
            app.GaussianFilterSlider.Limits = [0 5];
            app.GaussianFilterSlider.ValueChangingFcn = createCallbackFcn(app, @GaussianFilterSliderValueChanging, true);
            app.SelectthefilterDropDownLabel = uilabel(app.GridLayout2);
            app.SelectthefilterDropDownLabel.Text = 'Select the filter';
            app.SelectthefilterDropDown = uidropdown(app.GridLayout2);
            app.SelectthefilterDropDown.Items = {'None', 'Gaussian Filter', 'Box Filter'};
            app.SelectthefilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectthefilterDropDownValueChanged, true);
            app.SelectthefilterDropDown.Value = 'None';
            app.BoxFilterSpinnerLabel = uilabel(app.GridLayout2);
            app.BoxFilterSpinnerLabel.Text = 'Box Filter';
            app.BoxFilterSpinner = uispinner(app.GridLayout2);
            app.BoxFilterSpinner.Step = 2;
            app.BoxFilterSpinner.ValueChangingFcn = createCallbackFcn(app, @BoxFilterSpinnerValueChanging, true);
            app.BoxFilterSpinner.Limits = [1 11];
            app.BoxFilterSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_sub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end